classdef OneFlo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OneFloAnalysis                 matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImageTab                       matlab.ui.container.Tab
        ImagePlot                      matlab.ui.control.UIAxes
        PatchesTab                     matlab.ui.container.Tab
        RightPatchPlot                 matlab.ui.control.UIAxes
        LeftPatchPlot                  matlab.ui.control.UIAxes
        MaskTab                        matlab.ui.container.Tab
        MaskPlot                       matlab.ui.control.UIAxes
        OverlapTab                     matlab.ui.container.Tab
        AnalysisPlot                   matlab.ui.control.UIAxes
        ResultsTab                     matlab.ui.container.Tab
        ResultsPlot                    matlab.ui.control.UIAxes
        TabGroup2                      matlab.ui.container.TabGroup
        SetupTab                       matlab.ui.container.Tab
        PreProcessingPanel             matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        ImageResizingDimensionsLabel   matlab.ui.control.Label
        ConvertToDropDownLabel         matlab.ui.control.Label
        AnalysisModeDropDown           matlab.ui.control.DropDown
        PostProcessingPanel            matlab.ui.container.Panel
        AutomaticallySaveDataCheckBox  matlab.ui.control.CheckBox
        FileNameEditFieldLabel         matlab.ui.control.Label
        FileNameEditField              matlab.ui.control.EditField
        SaveButton                     matlab.ui.control.Button
        ProcessingPanel                matlab.ui.container.Panel
        ChosenImagesListBoxLabel       matlab.ui.control.Label
        ChosenImagesListBox            matlab.ui.control.ListBox
        ChosenPatchesTextAreaLabel     matlab.ui.control.Label
        ChosenPatchesTextArea          matlab.ui.control.TextArea
        SelectImagesButton             matlab.ui.control.Button
        AnalyzeButton                  matlab.ui.control.Button
        PatchSetupTab                  matlab.ui.container.Tab
        PatchSourceButtonGroup         matlab.ui.container.ButtonGroup
        LoadPatchesButton              matlab.ui.control.RadioButton
        SelectPatchesButton            matlab.ui.control.RadioButton
        ChangePathButton               matlab.ui.control.Button
        SelectPatchButton              matlab.ui.control.Button
        NumberofPatchesEditFieldLabel  matlab.ui.control.Label
        NumberofPatchesEditField       matlab.ui.control.NumericEditField
        InstructionsTextAreaLabel      matlab.ui.control.Label
        InstructionsTextArea           matlab.ui.control.TextArea
        PatchInformationPanel          matlab.ui.container.Panel
        PatchDataDirectoryTextAreaLabel  matlab.ui.control.Label
        PatchDataDirectoryTextArea     matlab.ui.control.TextArea
        LeftLabel                      matlab.ui.control.Label
        LeftListBox                    matlab.ui.control.ListBox
        UseLeftPatchCheckBox           matlab.ui.control.CheckBox
        RightLabel                     matlab.ui.control.Label
        RightListBox                   matlab.ui.control.ListBox
        UseRightPatchCheckBox          matlab.ui.control.CheckBox
        ConfirmPatchButton             matlab.ui.control.Button
        AnalysisTab_2                  matlab.ui.container.Tab
        MaskAdjustmentPanel            matlab.ui.container.Panel
        ThresholdSpinnerLabel          matlab.ui.control.Label
        ThresholdSpinner               matlab.ui.control.Spinner
        HeartsFoundEditFieldLabel      matlab.ui.control.Label
        HeartsFoundEditField           matlab.ui.control.NumericEditField
        ResultsPanel                   matlab.ui.container.Panel
        DataTypeDropDownLabel          matlab.ui.control.Label
        DataTypeDropDown               matlab.ui.control.DropDown
        PlotLabel                      matlab.ui.control.Label
        PlotDropDown                   matlab.ui.control.DropDown
        ErrorBarsCheckBox              matlab.ui.control.CheckBox
        OverlapAdjustmentPanel         matlab.ui.container.Panel
        HorizontalSpinnerLabel         matlab.ui.control.Label
        HorizontalSpinner              matlab.ui.control.Spinner
        VerticalSpinnerLabel           matlab.ui.control.Label
        VerticalSpinner                matlab.ui.control.Spinner
        ScanProgressGaugeLabel         matlab.ui.control.Label
        ScanProgressGauge              matlab.ui.control.LinearGauge
        Logo                           matlab.ui.control.Button
        StatusPanel                    matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        StatusDisplay                  matlab.ui.control.TextArea
        AreaMarkerPanel                matlab.ui.container.Panel
        HorizontalLabel                matlab.ui.control.Label
        HorizontalAreaSpinner          matlab.ui.control.Spinner
        VerticalSpinner_2Label         matlab.ui.control.Label
        VerticalAreaSpinner            matlab.ui.control.Spinner
        DebugButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        Image % Description
        NumImages % Description
        SetupImage % Description
        AnalyzedImage % Description
        PatchData % Description
        PatchUsed % Description
        NumPatches % Description
        Pearson % Description
        GaugeCorr % Description
        ReducedPearson % Description
        CombinedPatch % Description
        NumHearts % Description
        Results % Description

        ActiveImage % Description
        Completed % Description
        Status % Description
        Directory % Description
        Rectangles % Description
    end

    methods (Access = private)
    
        function appImageSetup(app,filename,path)
            n                   =   length(filename);
            plate_size          =   app.UITable.Data; 
            app.Image           =   cell(n,1);
            app.SetupImage      =   cell(n,1);
            app.NumImages       =   n;
            app.ActiveImage     =   1;
            app.AnalyzedImage   =   cell(n,1);
            app.Results.Data    =   cell(n,1);
            app.NumHearts       =   cell(n,1);
            app.Status          =   cell(n,1);
            app.Directory       =   cell(1,1);
            app.ChosenImagesListBox.Items       =   filename;
            app.ChosenImagesListBox.ItemsData   =   1:n;
            app.ChosenImagesListBox.Value       =   1;
            
            for i = 1:n
                full = fullfile(path,filename{i});
                Image        = imread(full);
                [h, w, d]    = size(Image);
                if w > h
                  Image      = imrotate(Image,90);
                end
                Image        = imresize(Image,plate_size);
                app.Image{i} = Image;
            end
            
            switch app.AnalysisModeDropDown.Value
                case 'HSV'
                   for i = 1:n
                        Image               =   double(rgb2hsv(app.Image{i}));
                        app.SetupImage{i}   =   Image(:,:,2); % Get Saturation for best fit
                   end
                case 'Gray'
                    for i = 1:n
                        app.SetupImage{i}   =   double(rgb2gray(app.Image{i}));
                    end
            end

        end
        
        function RightLeftPopulate(app)
            currPatch = app.PatchData;
            app.RightListBox.Items = {};
            app.LeftListBox.Items = {};
            
            PatchNames = fieldnames(currPatch);
            if isempty(PatchNames)
                app.LeftListBox.Enable = 'off';
                app.RightListBox.Enable ='off';
            else
                app.LeftListBox.Enable = 'on';
                app.RightListBox.Enable ='on';
                R = 1; L = 1;
                for i = 1:length(PatchNames)
                switch currPatch.(PatchNames{i}).Direction
                    case 'Right'
                        app.RightListBox.Items(R) = PatchNames(i);
                        R = R + 1;
                    case 'Left'
                        app.LeftListBox.Items(L) = PatchNames(i);
                        L = L + 1;
                end
                end
            end
        end
        
        function PlotPatch(app,PatchPlot,value)
            P = app.PatchData.(value).PatchImage;
            imagesc(app.(PatchPlot),P);
            app.(PatchPlot).YLim =[0, size(P,1)];
            app.(PatchPlot).XLim =[0, size(P,2)];
            colormap(app.(PatchPlot),'gray')
            app.TabGroup.SelectedTab = app.PatchesTab;
        end
        
        function PatchStruct = get_patch(app,n)

            Image = app.SetupImage{app.ChosenImagesListBox.Value};
            if isempty(Image)
                warndlg('No image uploaded','Silly...')
                return
            end
            plate_figure = figure;
            hold off
            imagesc(Image);
            colormap('gray');
            
            for i = 1:n
                accept_patch = 0;
                while accept_patch == 0
                    app.InstructionsTextArea.Value = 'Select two opposite corners of a rectangle, then press enter.';
                    [x,y]   =   getpts(plate_figure); 
                    x       =   sort(round(x));
                    y       =   sort(round(y));
                    
                    % Patch is generated and displayed
                    Patch   =   Image(y(1):y(2),x(1):x(2));
            
                    % Resize image to standard size
                    patch_figure = figure;
                    imagesc(Patch)
                    title('Selected Patch')
                    colormap('gray');
                    getframe();
                    
                    % Verify selection
                    accept_patch =YesNoQ(app,'Accept selected patch?','Patch Verification');
                    
                    % Close patch and allow for a new selection if not accepted
                    if accept_patch ~= 1
                        close(patch_figure)
                    end
                end
            
                % Get location for analysis center
                if accept_patch == 1 
                    app.InstructionsTextArea.Value = 'Click the location to be used for analysis.';
                    [x_analysis,y_analysis] =   ginput(1); 
                    x_analysis              =   sort(round(x_analysis));
                    y_analysis              =   sort(round(y_analysis));
                end
                direction = questdlg('What direction is the heart pointing towards?','Direction Selection','Right','Left','Right');
                
                % Create the structure
                PatchStruct = struct('PatchImage',Patch,...
                                    'AnalysisLocation', [x_analysis, y_analysis],...
                                    'OriginalImageSize',size(Image),...
                                    'OriginalLocation',[x y],...
                                    'Direction', direction);
                    % Save structure as .m
                    saveYN = YesNoQ(app,'Save patch?','Save Option');
                    if saveYN == 1
                        name = inputdlg('Enter a name for the patch: ');
                        dirname = app.PatchDataDirectoryTextArea.Value;
                        % Save image with input name to current directory 
                        load(dirname{1});
                        PatchData(1).(name{1}) = PatchStruct;
                        save(dirname{1},'PatchData');
                        app.PatchData = PatchData;
                    end
                close(patch_figure)
            end
            close(plate_figure)
            app.InstructionsTextArea.Value = '';
            
            InitializePatchData(app)
            RightLeftPopulate(app)
        end
        
        function val = YesNoQ(app,question,title)
        
            ansr = questdlg(question,title,'Yes','No','Cancel','Yes');
            
            % Handle response
            switch ansr
                case 'Yes'
                    val = 1;
                case 'No'
                    val = 0;
                case 'Cancel'
                    val = -1;
            end
        end

        
        function results = InitializePatchData(app)
            stored_patches_name = 'PatchData.mat';
            % Current Directory for Patches
            [directory,~,~] = fileparts(mfilename('fullpath')); 
            app.Directory{1} = directory;
            dirname = fullfile(directory,stored_patches_name);
            app.PatchDataDirectoryTextArea.Value = dirname;

            check = size(dir(dirname),1);
            if check == 0
                PatchData = struct();
                app.PatchData = PatchData;
                save(dirname,'PatchData') ;
            elseif check == 1
                PatchData= load(dirname) ;
                app.PatchData = PatchData.PatchData;
            end
        end
        
        function PatchStruct = sizing(app,Image,PatchStruct,n)
            Patch       =   PatchStruct.PatchImage;
            OrigSize    =   PatchStruct.OriginalImageSize;
            S_I         =   size(Image);  % size of full image
            S_P         =   size(Patch); % size of  patch
            y_rescale   =   S_I(1)/OrigSize(1); 
            x_rescale   =   S_I(2)/OrigSize(2);
            
            %% Rescaling of patch
            S_P(1)      =   round(y_rescale*S_P(1));
            S_P(2)      =   round(x_rescale*S_P(2));
            dlim        =   0.4*sqrt(S_P(1)^2+S_P(2)^2);
            
            PatchStruct.PatchImage = imresize(Patch, S_P);
            PatchStruct.dlim       = dlim;
            
            %% Rescaling of analys Location
            S_AL = PatchStruct.AnalysisLocation;
            Y_P = round(y_rescale*S_AL(2));
            X_P = round(x_rescale*S_AL(1));
            
            PatchStruct.UsedAnalysisLocation{n} = [X_P, Y_P];
        end
        
        
        function Pearson = PearsonCorr(app,Image,PatchStruct)
        
            Patch   =   PatchStruct.PatchImage;
            [Y_width, X_width] = size(Patch); % size of  patch
            Y_width = Y_width -1;
            X_width = X_width -1;
            
            [Y, X] = size(Image);
        
            Pearson         =   zeros(Y,X);                     % Correlation
            N               =   numel(Patch);                   % Number of pixels in patch image
            Patch_Reshape   =   reshape(Patch,[1,N]);           % Single array of the patch image
            Patch_M         =   mean(Patch_Reshape);            % Mean intensity of the patch image
            Patch_S         =   std(Patch_Reshape);             % Standard deviation of the patch image 
        
            % Translation of the patch around the original image is performed to find
            % maximum values of the correlation coefficient
            rows = Y-Y_width;
            cols = X-X_width;
  
            for i=1:2:rows
                for j=1:2:cols
                    Target          =   Image(i:(i+Y_width),j:(j+X_width)); % Target from the original image is sampled
                    Target_Reshape  =   reshape(Target,[1,numel(Target)]);      % Reshaped
                    Target_M        =   mean(Target_Reshape);               
                    Target_S        =   std(Target_Reshape);
        
                    % Calculation of the pearson correlation coefficient for that area
                    % of the image:
                    Pearson(i,j)=sum((Target_Reshape-Target_M).*(Patch_Reshape-Patch_M))/(Target_S*Patch_S)/N; 
                end
                patchcorr = (i/(app.GaugeCorr(2)*rows)+(app.GaugeCorr(1)-1)/app.GaugeCorr(2));
                app.ScanProgressGauge.Value = patchcorr*100;
                pause(0.0001);  % For refreshing GUI
            end
          app.GaugeCorr(1) = app.GaugeCorr(1) +1;
        
        end
        function M = sparse_reduction(app,mat,dlim)
        
            s_mat       =   sparse(mat);    % convert matrix to sparse notation
            [r, c]      =   size(mat);
            [i, j, v]   =   find(s_mat);   % extract rows and cols of non-zero vals
            counter     =   1;
        
            while counter-1 < length(i)
                [i, j, v] = sparse_dist(app,[i, j, v],counter,dlim);
                counter     = counter+1;
            end
            
            M = sparse(i,j,v,r,c);
        end
        function [i, j, v] = sparse_dist(app,IJV,counter,dlim)
        
            N               =   size(IJV,1);
            distances       =   zeros(N-counter,1);
            start_i         =   IJV(counter,1); 
            start_j         =   IJV(counter,2); 
            start_v         =   IJV(counter,3);
            remaining_IJV   =   IJV(counter+1:end,:);
        
            % Find distances between all points
            for k = 1:(N-counter)
                distances(k) = sqrt((IJV(counter+k,1)-(start_i))^2 + (IJV(counter+k,2) - start_j)^2);
            end
            
            vals        = distances > dlim;
            new_rows    = length(vals(vals==1));
            new_end     = counter+new_rows;
            
            unclustered_IJV                     =   remaining_IJV(vals,:);
            clustered_IJV                       =   remaining_IJV(~vals,:);
            IJV_reduced                         =   zeros(new_end,3);
            IJV_reduced(1:counter,:)            =   IJV(1:counter,:);
            IJV_reduced(counter+1:new_end,:)    =   unclustered_IJV;
            
            % Find maximum in a cluster
            [maxval, ind] = max(clustered_IJV(:,3));
            if maxval > start_v
                IJV_reduced(counter,3)          =   maxval;
                IJV_reduced(counter,1:2)        =   clustered_IJV(ind,1:2);
            end
            % Output
            i = IJV_reduced(:,1); j = IJV_reduced(:,2); v = IJV_reduced(:,3);
            
        end

        function results = Evaluate(app,k)
            DataTypeDropDownValueChanged(app)
            [app.Results.Data{k}, app.Status{k}] =  getData(app,k);
            marker = CreateRectangle(app,k);
            PlotOverlap(app,marker)
        end
        
        function [Data, Status] = getData(app,n)
            
             Image          =   app.AnalyzedImage{n};
             NumSections    =   app.CombinedPatch.NumSections{n};
             A              =   app.CombinedPatch.LayerStruct{n} ;
             Height_adj     =   app.VerticalAreaSpinner.Value;
             Width_adj      =   app.HorizontalAreaSpinner.Value;
             
             c              =   0 ; % counter
             Data           = zeros(app.NumHearts{n},8);
             
             for k = 1: NumSections
                 x   =   A{k}(:,1); 
                 y   =   A{k}(:,2);

                 for i = 1:length(x)
                     c=c+1;
                     
                     Data(c,1) = x(i);
                     Data(c,2) = y(i);
        
                     % For these, the Image takes the "height" pixel coordinate before
                     % the "width," so must flip the y and x coordinates

                     Slice = Image(y(i)-Height_adj:y(i)+Height_adj,x(i)-Width_adj:x(i)+Width_adj,:);
                     L1 = Slice(:,:,1); %Layer 1 - H / R/ etc..
                     L2 = Slice(:,:,2); %Layer 2 - S / G/ etc..
                     L3 = Slice(:,:,3); %Layer 3 - V / B/ etc..
                     
                     % Average Values
                     Data(c,3) = mean(L1(:)); % Hue / R / etc..
                     Data(c,4) = mean(L2(:)); % S / G / etc.. 
                     Data(c,5) = mean(L3(:)); % V / B / etc..
                     
                     % Standard Deviation
                     Data(c,6) = std(L1(:)); % Hue / R / etc..
                     Data(c,7) = std(L2(:)); % S / G / etc.. 
                     Data(c,8) = std(L3(:)); % V / B / etc..
                   end
             end
             Status = ThresholdAdjustment(app, n, c);
            
        end


        function A = layerplate(app,mat,direction)
        
            x = mat(:,1);
            y = mat(:,2);
            n = length(y);
            i = 1;
            
            while n > 0
                maxval  =   y(n);
                inLayer =   y(y>(maxval-20) & y<(maxval+20));
                m       =   length(inLayer)-1;
                
                a       =   x(n-m:n);
                b       =   y(n-m:n);
                
                switch direction
                    case 'Right'
                        [a, ind] = sort(a,'ascend');
                    case 'Left'
                        [a, ind] = sort(a,'descend');
                end
                
                b       =   b(ind);
                A{i}    =   [a, b];
                n       =   n-m-1;
                i       =   i+1;
            end
        
        end

        function [NS, LS, NH] = Overlap(app,RP,n)
            
            Patch = app.PatchUsed;
            num_patches = app.NumPatches;
            iv = app.HorizontalSpinner.Value;
            jv = app.VerticalSpinner.Value;
            
            A  = cell(num_patches,1);
            NS = 0; % Number of Structures
            NH = 0; % Number of Hearts
            
            for k = 1:num_patches
                [i, j]  =   find(RP{k}');
                i_adj   =   Patch{k}.UsedAnalysisLocation{n}(1);
                j_adj   =   Patch{k}.UsedAnalysisLocation{n}(2);
                i       =   i + i_adj+iv; 
                j       =   j+ j_adj+jv;
                A{k}    =   layerplate(app,[i,j],Patch{k}.Direction);
                NH      =   NH + length(i);
                NS      =   NS + length(A{k});
            end

            LS    =   cell(NS,1);   %Properly Ordered hearts
            for k = 1:num_patches
                for z = 1:length(A{k})
                    LS{num_patches*z+k-num_patches} = A{k}{z};
                end
            end
        end
        
        function [RP, S, NH] = ReducedPearsonCalc(app,k,Threshold)
            TP = cell(app.NumPatches,1);
            RP = cell(app.NumPatches,1);
            S = 0;
                for i = 1:app.NumPatches
                    TP{i} = app.Pearson{k,i}>(Threshold*max(max(app.Pearson{i})))*ones(size(app.Pearson{i}));
                    RP{i} = app.Pearson{k,i} .* TP{i};
                    RP{i} = sparse_reduction(app,RP{i},app.PatchUsed{i}.dlim);
                    S= S +RP{i};
                end

            [Y, X] = find(S);
            NH = length(X);
            app.NumHearts{k} = length(X);
        end
        
        function PlotOverlap(app, marker)
            n     = app.ActiveImage;
            Image = app.SetupImage{n};
            
            cla(app.AnalysisPlot);

            colormap(app.AnalysisPlot,'gray');
            imagesc(app.AnalysisPlot,Image);
            app.AnalysisPlot.YLim =[0, app.UITable.Data(1,1)];
            app.AnalysisPlot.XLim =[0, app.UITable.Data(1,2)];
            hold(app.AnalysisPlot,'on');

            for i = 1:size(marker,1)
               rectangle(app.AnalysisPlot,'Position',marker(i,:),'FaceColor','w','EdgeColor','b');
            end
        end
        
        function results = UpdatePlots(app)
            k               = app.ChosenImagesListBox.Value;
            Threshold       = app.ThresholdSpinner.Value;
            app.ActiveImage = k;
            
            marker = CreateRectangle(app,k);
            
            % Show plots of selected images
            ThresholdPlot(app, k, Threshold);
            PlotOverlap(app, marker);
            DataTypeDropDownValueChanged(app);
            app.StatusDisplay.Value = app.Status{k};
        end
        
        function results = PackageResult(app)
            NP = app.NumPatches;
            NI = app.NumImages;
            
            results.Patches     = cell(NP,1);
            results.Images      = cell(NI,1);
            results.Grid        = cell(NI,1);
            results.Data        = cell(NI,1);
            results.StDev       = cell(NI,1);
            
            results.Status      = cell(NI,1);
            results.Settings    = struct();
            
            results.Patches     = app.ChosenPatchesTextArea.Value;
            results.Images      = app.ChosenImagesListBox.Items;
            results.DataFormat  = app.DataTypeDropDown.Value;
            results.Status      = app.Status;
            
            for i = 1:NI
                results.Grid{i} = app.Results.Data{i}(:,1:2);
                results.Data{i} = app.Results.Data{i}(:,3:5);
                results.StDev{i}= app.Results.Data{i}(:,6:8);
            end
            
            results.Settings.Threshold      =     app.ThresholdSpinner.Value;
            results.Settings.Adjustment     =     [app.HorizontalSpinner.Value, app.VerticalSpinner.Value];
            results.Settings.PearsonImage   =     app.AnalysisModeDropDown.Value;

 
        end
        
        function SaveResults(app)
            results     =  PackageResult(app);
            savename    =  app.FileNameEditField.Value;
            file = horzcat(app.Directory{1},savename,'.mat');
            save(file,'results');
            app.FileNameEditField.Value = datestr(now,'mmmm-dd-yyyy HH-MM-SS');
        end
        
        function Status = ThresholdAdjustment(app, n, NH_D)

            NH_S = app.NumHearts{n};

            diff = NH_S - NH_D;
            if diff==0
                Status = {'OK'};
            else
                T = app.ThresholdSpinner.Value; % Starting Threshold value
                max_T = 0.8;
                while diff ~= 0 && T < max_T
                    T = T + 0.2;
                    [RP, ~, NH_S]  = ReducedPearsonCalc(app,n,T);
                    [~, ~, NH_D]   = Overlap(app,RP,n);
                    diff = NH_S - NH_D;
                    if diff == 0 
                        Status = {'Error',sprintf('Threshold adjusted to %0.1f',T),'Resolved'};
                        break
                    end
                end
                if diff ~= 0
                    Status = {'Error','Threshold adjusted, consider new patches or cropping', 'Unresolved'};
                end
            end

        end
        
        function ThresholdPlot(app,n, Threshold)
            [~, S, NH] = ReducedPearsonCalc(app,n, Threshold);
            [Y, X] = find(S);
            plot(app.MaskPlot,X,-Y,'b.');
            app.MaskPlot.YLim =[-app.UITable.Data(1,1),0];
            app.MaskPlot.XLim =[0, app.UITable.Data(1,2)];
            app.HeartsFoundEditField.Value = NH;
        end
        
        function results = PlotResults(app, value, n)
           Data     =   app.Results.Data{n};
           nvals    =   length(Data(:,1));
           DataPts  =   Data(:,value);
           err      =   Data(:,value+3);

           if app.ErrorBarsCheckBox.Value == 1 
                errorbar(app.ResultsPlot, [1:nvals], DataPts,err,'ks','LineStyle','none')
           else
                plot(app.ResultsPlot, [1:nvals], DataPts,'ks')
           end
           
           app.ResultsPlot.YLabel.String = app.PlotDropDown.Items(value-2);
        end
        
        
        function marker = CreateRectangle(app, n)
            Data    = app.Results.Data{n};
            vals    = size(Data,1);
            marker  = zeros(vals,4);
            
            x = Data(:,1);
            y = Data(:,2);
            Height_adj     =   app.VerticalAreaSpinner.Value;
            Width_adj      =   app.HorizontalAreaSpinner.Value;
            
            for i = 1:vals
                 % For marker positioning outline
                marker(i,:) = [x(i)-Width_adj, y(i)-Height_adj, ...
                            2*Width_adj, 2*Height_adj];
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Default Image Resizing
            app.UITable.Data =[2048, 1365];
            
            InitializePatchData(app)
            RightLeftPopulate(app)
            app.Completed = 0;
            date =datestr(now,'mmmm-dd-yyyy_HH-MM');
            
            app.FileNameEditField.Value = date;
            app.Completed = 0;
        end

        % Callback function
        function AnalyzeButtonPushed(app, event)
            
            if isempty(app.ChosenPatchesTextArea.Value) || isempty(app.ChosenImagesListBox.Value)
                warndlg('No Images or Patches Selected.')
                return
            end
            
            app.TabGroup2.SelectedTab = app.AnalysisTab_2;
            pause(0.01);
            app.ScanProgressGauge.Value = 0;
            app.GaugeCorr = [1 app.NumImages*app.NumPatches];

            if app.Completed ~= 1
            app.Pearson = cell(app.NumImages,app.NumPatches);
                for k = 1:app.NumImages
                    app.ActiveImage = k;
                    for i = 1:app.NumPatches
                        app.PatchUsed{i}  =   sizing(app,app.SetupImage{k},app.PatchUsed{i},k);
                        app.Pearson{k,i}  =   PearsonCorr(app,app.SetupImage{k},app.PatchUsed{i});
                    end
                end
            end
            for k = 1:app.NumImages
                app.ActiveImage = k;
                Threshold = app.ThresholdSpinner.Value;
                app.ReducedPearson{k} = ReducedPearsonCalc(app,k,Threshold);
                
                [NS, LS, ~] = Overlap(app, app.ReducedPearson{k},k);
                app.CombinedPatch.NumSections{k} = NS;
                app.CombinedPatch.LayerStruct{k} = LS;
                Evaluate(app,k);
            end
            
            if app.AutomaticallySaveDataCheckBox.Value == 1
                SaveResults(app)
            end
            UpdatePlots(app);
            
            app.Completed = 1;
            
        end

        % Callback function
        function ChangePathButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            if strcmp(class(filename),'double')
                return
            end
            
            full = fullfile(path,filename);
            app.PatchDataDirectoryTextArea.Value = full;
            app.Directory{1} = path;
            
            PatchData=load(full);
            app.PatchData  = PatchData.PatchData;
            RightLeftPopulate(app)
        end

        % Callback function
        function ChosenImagesListBoxValueChanged(app, event)
            value = app.ChosenImagesListBox.Value;
            imagesc(app.ImagePlot,app.Image{value});
            app.ActiveImage = value;
            if app.Completed == 1
                UpdatePlots(app);
            end
        end

        % Callback function
        function ConfirmPatchButtonPushed(app, event)
            if app.LoadPatchesButton.Value == 1
                i = 0;
                valueR = app.RightListBox.Value;
                valueL = app.LeftListBox.Value;
                if app.UseRightPatchCheckBox.Value == 1 && ~isempty(valueR)
                    i=i+1;
                    value = app.RightListBox.Value;
                    app.PatchUsed{i} = app.PatchData.(valueR);
                    names{i} = value;
                end
                if app.UseLeftPatchCheckBox.Value == 1 && ~isempty(valueL)
                    i=i+1;
                    value = app.LeftListBox.Value;
                    app.PatchUsed{i} = app.PatchData.(valueL);
                    names{i} = value;
                end  
                if i ~= 0 
                    app.ChosenPatchesTextArea.Value = names;
                    app.NumPatches = i;
                    app.TabGroup2.SelectedTab = app.SetupTab;
                else
                    warndlg('No patches selected.');
                end
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
        if isempty(app.Image)
            return
        else
            value = app.DataTypeDropDown.Value;
            plate_size      =   app.UITable.Data;
            n = app.ActiveImage;
            switch value
                case 'HSV'
                    app.PlotDropDown.Items = {'H', 'S', 'V'};
                    app.AnalyzedImage{n}   =   rgb2hsv(app.Image{n});
                case 'RGB'
                    app.PlotDropDown.Items = {'R', 'G', 'B'};
                    app.AnalyzedImage{n}    =  double(app.Image{n});
            end
        end
        [app.Results.Data{n}, app.Status{n}] =  getData(app,n);
        PlotDropDownValueChanged(app);
        end

        % Callback function
        function DebugButtonPushed(app, event)
            keyboard
        end

        % Value changed function: HorizontalSpinner
        function HorizontalSpinnerValueChanged(app, event)
            if app.Completed == 1
                n = app.ActiveImage;
                [NS, LS, ~] = Overlap(app,app.ReducedPearson{n},n);
                marker = CreateRectangle(app,n);
                PlotOverlap(app,marker);
            end
        end

        % Callback function
        function LeftListBoxValueChanged(app, event)
            value = app.LeftListBox.Value;
            PlotPatch(app,'LeftPatchPlot',value)
        end

        % Callback function
        function PatchSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.PatchSourceButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Load Patches'
                    app.SelectPatchButton.Enable = 'off';
                    app.NumberofPatchesEditField.Enable = 'off';
                    app.InstructionsTextArea.Enable = 'off';
                    
                    app.RightListBox.Enable = 'on';
                    app.LeftListBox.Enable = 'on';
                    app.UseRightPatchCheckBox.Enable = 'on';
                    app.UseLeftPatchCheckBox.Enable = 'on';
                case 'Select Patches'
                    app.SelectPatchButton.Enable = 'on';
                    app.NumberofPatchesEditField.Enable = 'on';
                    app.InstructionsTextArea.Enable = 'on';
                    
                    app.RightListBox.Enable = 'off';
                    app.LeftListBox.Enable = 'off';
                    app.UseRightPatchCheckBox.Enable = 'off';
                    app.UseLeftPatchCheckBox.Enable = 'off';
            end
            
        end

        % Value changed function: ErrorBarsCheckBox, PlotDropDown
        function PlotDropDownValueChanged(app, event)
            value = str2num(app.PlotDropDown.Value);
            if isempty(app.Results)
               return
            else
               n  =   app.ActiveImage;
               PlotResults(app, value, n)
            end
        end

        % Callback function
        function RightListBoxValueChanged(app, event)
            value = app.RightListBox.Value;
            PlotPatch(app,'RightPatchPlot',value);
        end

        % Callback function
        function SaveButtonPushed(app, event)
            if app.Completed == 0
                warndlg('No data to save. Run analysis first.');
                return
            else
                SaveResults(app);
            end
        end

        % Callback function
        function SelectImageButtonPushed(app, event)
            
            [filename, path] = uigetfile('*.*','MultiSelect','on');
            if strcmp(class(filename),'double')
                return
            elseif strcmp(class(filename),'cell') == 0 
                filename = {filename};
            end
            appImageSetup(app,filename,path);
            imagesc(app.ImagePlot,app.Image{1});
            app.ImagePlot.YLim =[0, app.UITable.Data(1,1)];
            app.ImagePlot.XLim =[0, app.UITable.Data(1,2)];
            app.TabGroup.SelectedTab = app.ImageTab;
            app.TabGroup2.SelectedTab = app.PatchSetupTab;
            app.Completed = 0 ;
        end

        % Callback function
        function SelectPatchButtonPushed(app, event)
              if isempty(app.ChosenPatchesTextArea.Value)
                warndlg('No Images imported.')
                return
            end
            n = app.NumberofPatchesEditField.Value;
                         if ~strcmp(class(n),'double') || n < 1
                             return
                         end
                
            get_patch(app,n);
            app.LoadPatchesButton.Value = 1;
            PatchSourceButtonGroupSelectionChanged(app)
            
        end

        % Value changed function: ThresholdSpinner
        function ThresholdSpinnerValueChanged(app, event)
            Threshold = app.ThresholdSpinner.Value;
            if app.Completed == 1
                k = app.ActiveImage;
                ThresholdPlot(app,k,Threshold)
            end
        end

        % Value changed function: VerticalSpinner
        function VerticalSpinnerValueChanged(app, event)
            if app.Completed == 1
                n = app.ActiveImage;
                [NS, LS, ~] = Overlap(app,app.ReducedPearson{n},n);
                marker = CreateRectangle(app,n);
                PlotOverlap(app,marker);
            end
        end

        % Value changed function: HorizontalAreaSpinner
        function HorizontalAreaSpinnerValueChanged(app, event)
            value = app.HorizontalAreaSpinner.Value;
            if app.Completed == 1
                n = app.ActiveImage;
                marker = CreateRectangle(app,n);
                PlotOverlap(app,marker);
            end
        end

        % Value changed function: VerticalAreaSpinner
        function AreaVerticalSpinnerValueChanged(app, event)
            value = app.VerticalAreaSpinner.Value;
            if app.Completed == 1
                n = app.ActiveImage;
                marker = CreateRectangle(app,n);
                PlotOverlap(app,marker);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OneFloAnalysis = uifigure;
            app.OneFloAnalysis.Name = 'OneFlo Analysis';
            app.TabGroup = uitabgroup(app.OneFloAnalysis);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ImagePlot = uiaxes(app.ImageTab);
            app.ImagePlot.XTick = [];
            app.ImagePlot.YTick = [];
            app.PatchesTab = uitab(app.TabGroup);
            app.PatchesTab.Title = 'Patches';
            app.RightPatchPlot = uiaxes(app.PatchesTab);
            app.LeftPatchPlot = uiaxes(app.PatchesTab);
            app.MaskTab = uitab(app.TabGroup);
            app.MaskTab.Title = 'Mask';
            app.MaskPlot = uiaxes(app.MaskTab);
            app.MaskPlot.XTick = [];
            app.MaskPlot.YTick = [];
            app.OverlapTab = uitab(app.TabGroup);
            app.OverlapTab.Title = 'Overlap';
            app.AnalysisPlot = uiaxes(app.OverlapTab);
            app.AnalysisPlot.XTick = [];
            app.AnalysisPlot.YTick = [];
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ResultsPlot = uiaxes(app.ResultsTab);
            xlabel(app.ResultsPlot, 'Heart Number')
            app.TabGroup2 = uitabgroup(app.OneFloAnalysis);
            app.SetupTab = uitab(app.TabGroup2);
            app.SetupTab.Title = 'Setup';
            app.PreProcessingPanel = uipanel(app.SetupTab);
            app.PreProcessingPanel.Title = 'Pre-Processing';
            app.UITable = uitable(app.PreProcessingPanel);
            app.UITable.ColumnName = {'Height'; 'Width'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ImageResizingDimensionsLabel = uilabel(app.PreProcessingPanel);
            app.ImageResizingDimensionsLabel.Text = {'Image '; 'Resizing '; 'Dimensions'};
            app.ConvertToDropDownLabel = uilabel(app.PreProcessingPanel);
            app.ConvertToDropDownLabel.Text = 'Convert To:';
            app.AnalysisModeDropDown = uidropdown(app.PreProcessingPanel);
            app.AnalysisModeDropDown.Items = {'HSV', 'Gray'};
            app.AnalysisModeDropDown.Value = 'HSV';
            app.PostProcessingPanel = uipanel(app.SetupTab);
            app.PostProcessingPanel.Title = 'Post-Processing';
            app.AutomaticallySaveDataCheckBox = uicheckbox(app.PostProcessingPanel);
            app.AutomaticallySaveDataCheckBox.Text = 'Automatically Save Data';
            app.AutomaticallySaveDataCheckBox.Value = true;
            app.FileNameEditFieldLabel = uilabel(app.PostProcessingPanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.PostProcessingPanel, 'text');
            app.SaveButton = uibutton(app.PostProcessingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ProcessingPanel = uipanel(app.SetupTab);
            app.ProcessingPanel.Title = 'Processing';
            app.ChosenImagesListBoxLabel = uilabel(app.ProcessingPanel);
            app.ChosenImagesListBoxLabel.Text = {'Chosen'; 'Images'};
            app.ChosenImagesListBox = uilistbox(app.ProcessingPanel);
            app.ChosenImagesListBox.Items = {};
            app.ChosenImagesListBox.ValueChangedFcn = createCallbackFcn(app, @ChosenImagesListBoxValueChanged, true);
            app.ChosenImagesListBox.Value = {};
            app.ChosenPatchesTextAreaLabel = uilabel(app.ProcessingPanel);
            app.ChosenPatchesTextAreaLabel.Text = {'Chosen '; 'Patches'};
            app.ChosenPatchesTextArea = uitextarea(app.ProcessingPanel);
            app.ChosenPatchesTextArea.Editable = 'off';
            app.SelectImagesButton = uibutton(app.ProcessingPanel, 'push');
            app.SelectImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImagesButton.Text = 'Select Images';
            app.AnalyzeButton = uibutton(app.ProcessingPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.PatchSetupTab = uitab(app.TabGroup2);
            app.PatchSetupTab.Title = 'Patch Setup';
            app.PatchSourceButtonGroup = uibuttongroup(app.PatchSetupTab);
            app.PatchSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PatchSourceButtonGroupSelectionChanged, true);
            app.PatchSourceButtonGroup.Title = 'Patch Source';
            app.LoadPatchesButton = uiradiobutton(app.PatchSourceButtonGroup);
            app.LoadPatchesButton.Text = 'Load Patches';
            app.LoadPatchesButton.Value = true;
            app.SelectPatchesButton = uiradiobutton(app.PatchSourceButtonGroup);
            app.SelectPatchesButton.Text = 'Select Patches';
            app.ChangePathButton = uibutton(app.PatchSourceButtonGroup, 'push');
            app.ChangePathButton.ButtonPushedFcn = createCallbackFcn(app, @ChangePathButtonPushed, true);
            app.ChangePathButton.Text = 'Change Path';
            app.SelectPatchButton = uibutton(app.PatchSourceButtonGroup, 'push');
            app.SelectPatchButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPatchButtonPushed, true);
            app.SelectPatchButton.Enable = 'off';
            app.SelectPatchButton.Text = 'Select Patch';
            app.NumberofPatchesEditFieldLabel = uilabel(app.PatchSourceButtonGroup);
            app.NumberofPatchesEditFieldLabel.Enable = 'off';
            app.NumberofPatchesEditFieldLabel.Text = 'Number of Patches:';
            app.NumberofPatchesEditField = uieditfield(app.PatchSourceButtonGroup, 'numeric');
            app.NumberofPatchesEditField.Limits = [1 10];
            app.NumberofPatchesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofPatchesEditField.Enable = 'off';
            app.NumberofPatchesEditField.Value = 2;
            app.InstructionsTextAreaLabel = uilabel(app.PatchSourceButtonGroup);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.PatchSourceButtonGroup);
            app.PatchInformationPanel = uipanel(app.PatchSetupTab);
            app.PatchInformationPanel.Title = 'Patch Information';
            app.PatchDataDirectoryTextAreaLabel = uilabel(app.PatchInformationPanel);
            app.PatchDataDirectoryTextAreaLabel.Text = 'Patch Data Directory';
            app.PatchDataDirectoryTextArea = uitextarea(app.PatchInformationPanel);
            app.PatchDataDirectoryTextArea.Editable = 'off';
            app.LeftLabel = uilabel(app.PatchInformationPanel);
            app.LeftLabel.Text = 'Left:';
            app.LeftListBox = uilistbox(app.PatchInformationPanel);
            app.LeftListBox.Items = {};
            app.LeftListBox.ValueChangedFcn = createCallbackFcn(app, @LeftListBoxValueChanged, true);
            app.LeftListBox.Value = {};
            app.UseLeftPatchCheckBox = uicheckbox(app.PatchInformationPanel);
            app.UseLeftPatchCheckBox.Text = '';
            app.UseLeftPatchCheckBox.Value = true;
            app.RightLabel = uilabel(app.PatchInformationPanel);
            app.RightLabel.Text = 'Right:';
            app.RightListBox = uilistbox(app.PatchInformationPanel);
            app.RightListBox.Items = {};
            app.RightListBox.ValueChangedFcn = createCallbackFcn(app, @RightListBoxValueChanged, true);
            app.RightListBox.Value = {};
            app.UseRightPatchCheckBox = uicheckbox(app.PatchInformationPanel);
            app.UseRightPatchCheckBox.Text = '';
            app.UseRightPatchCheckBox.Value = true;
            app.ConfirmPatchButton = uibutton(app.PatchSetupTab, 'push');
            app.ConfirmPatchButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmPatchButtonPushed, true);
            app.ConfirmPatchButton.Text = 'Confirm Patch';
            app.AnalysisTab_2 = uitab(app.TabGroup2);
            app.AnalysisTab_2.Title = 'Analysis';
            app.MaskAdjustmentPanel = uipanel(app.AnalysisTab_2);
            app.MaskAdjustmentPanel.Title = 'Mask Adjustment';
            app.ThresholdSpinnerLabel = uilabel(app.MaskAdjustmentPanel);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.MaskAdjustmentPanel);
            app.ThresholdSpinner.Step = 0.01;
            app.ThresholdSpinner.Limits = [0 1];
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThresholdSpinnerValueChanged, true);
            app.ThresholdSpinner.Value = 0.5;
            app.HeartsFoundEditFieldLabel = uilabel(app.MaskAdjustmentPanel);
            app.HeartsFoundEditFieldLabel.Text = 'Hearts Found';
            app.HeartsFoundEditField = uieditfield(app.MaskAdjustmentPanel, 'numeric');
            app.HeartsFoundEditField.Limits = [0 Inf];
            app.HeartsFoundEditField.ValueDisplayFormat = '%.0f';
            app.HeartsFoundEditField.Editable = 'off';
            app.ResultsPanel = uipanel(app.AnalysisTab_2);
            app.ResultsPanel.Title = 'Results';
            app.DataTypeDropDownLabel = uilabel(app.ResultsPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.ResultsPanel);
            app.DataTypeDropDown.Items = {'HSV', 'RGB'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'HSV';
            app.PlotLabel = uilabel(app.ResultsPanel);
            app.PlotLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.ResultsPanel);
            app.PlotDropDown.Items = {'H', 'S', 'V'};
            app.PlotDropDown.ItemsData = {'3', '4', '5'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Value = '4';
            app.ErrorBarsCheckBox = uicheckbox(app.ResultsPanel);
            app.ErrorBarsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.ErrorBarsCheckBox.Text = {'Error '; 'Bars'};
            app.ErrorBarsCheckBox.Value = true;
            app.OverlapAdjustmentPanel = uipanel(app.AnalysisTab_2);
            app.OverlapAdjustmentPanel.Title = 'Overlap Adjustment';
            app.HorizontalSpinnerLabel = uilabel(app.OverlapAdjustmentPanel);
            app.HorizontalSpinnerLabel.Text = 'Horizontal';
            app.HorizontalSpinner = uispinner(app.OverlapAdjustmentPanel);
            app.HorizontalSpinner.Limits = [-300 300];
            app.HorizontalSpinner.ValueChangedFcn = createCallbackFcn(app, @HorizontalSpinnerValueChanged, true);
            app.VerticalSpinnerLabel = uilabel(app.OverlapAdjustmentPanel);
            app.VerticalSpinnerLabel.Text = 'Vertical';
            app.VerticalSpinner = uispinner(app.OverlapAdjustmentPanel);
            app.VerticalSpinner.Limits = [-300 300];
            app.VerticalSpinner.ValueChangedFcn = createCallbackFcn(app, @VerticalSpinnerValueChanged, true);
            app.ScanProgressGaugeLabel = uilabel(app.AnalysisTab_2);
            app.ScanProgressGaugeLabel.Text = 'Scan Progress';
            app.ScanProgressGauge = uigauge(app.AnalysisTab_2, 'linear');
            app.Logo = uibutton(app.AnalysisTab_2, 'push');
            app.Logo.Icon = 'logo.png';
            app.Logo.Text = '';
            app.StatusPanel = uipanel(app.AnalysisTab_2);
            app.StatusPanel.Title = 'Status';
            app.Label = uilabel(app.StatusPanel);
            app.Label.Text = '';
            app.StatusDisplay = uitextarea(app.StatusPanel);
            app.AreaMarkerPanel = uipanel(app.AnalysisTab_2);
            app.AreaMarkerPanel.Title = 'Area Marker';
            app.HorizontalLabel = uilabel(app.AreaMarkerPanel);
            app.HorizontalLabel.Text = 'Horizontal ';
            app.HorizontalAreaSpinner = uispinner(app.AreaMarkerPanel);
            app.HorizontalAreaSpinner.Limits = [1 300];
            app.HorizontalAreaSpinner.ValueDisplayFormat = '%.0f';
            app.HorizontalAreaSpinner.ValueChangedFcn = createCallbackFcn(app, @HorizontalAreaSpinnerValueChanged, true);
            app.HorizontalAreaSpinner.Value = 5;
            app.VerticalSpinner_2Label = uilabel(app.AreaMarkerPanel);
            app.VerticalSpinner_2Label.Text = 'Vertical';
            app.VerticalAreaSpinner = uispinner(app.AreaMarkerPanel);
            app.VerticalAreaSpinner.Limits = [1 300];
            app.VerticalAreaSpinner.ValueChangedFcn = createCallbackFcn(app, @AreaVerticalSpinnerValueChanged, true);
            app.VerticalAreaSpinner.Value = 5;
            app.DebugButton = uibutton(app.OneFloAnalysis, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OneFlo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OneFloAnalysis)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OneFloAnalysis)
        end
    end
end